classdef DepthReconstruction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        Label                     matlab.ui.control.Label
        Ligenshi88comLabel        matlab.ui.control.Label
        CONTACTLabel              matlab.ui.control.Label
        initialization            matlab.ui.control.Button
        LoadLF                    matlab.ui.control.Button
        run                       matlab.ui.control.Button
        save                      matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
        DminEditFieldLabel        matlab.ui.control.Label
        DminEditField             matlab.ui.control.NumericEditField
        DmaxEditFieldLabel        matlab.ui.control.Label
        DmaxEditField             matlab.ui.control.NumericEditField
        dEditFieldLabel           matlab.ui.control.Label
        dEditField                matlab.ui.control.NumericEditField
        LFformatEditField_2Label  matlab.ui.control.Label
        LFformatEditField         matlab.ui.control.EditField
        LFpathEditField_2Label    matlab.ui.control.Label
        LFpathEditField           matlab.ui.control.EditField
        Label_2                   matlab.ui.control.Label
        DropDown                  matlab.ui.control.DropDown
        Label_3                   matlab.ui.control.Label
        DropDown_2                matlab.ui.control.DropDown
        initialization_2          matlab.ui.control.Button
        Label_4                   matlab.ui.control.Label
        TextArea                  matlab.ui.control.TextArea
        Label_5                   matlab.ui.control.Label
        DISPALYLabel              matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Property % Description
        file_path;
        LFformat;
        s_max;
        s_min;
        d=0.1;
        LF;
        Disparity;
        flag;
    end
    
    methods (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             msgbox("欢迎使用深度重建软件!");
             app.DmaxEditField.Value=2.0;
             app.DminEditField.Value=-1.7;
             app.dEditField.Value=0.1;
             app.LFformatEditField.Value='png';
             app.LFpathEditField.Value='.\lightfield\'; 
             app.flag=0;
             app.TextArea.Value = "欢迎使用深度重建软件!";
        end

        % Button pushed function: run
        function runPushed(app, event)
            app.TextArea.Value = "正在进行深度重建!";
            app.flag=0;
            pause(1);
            I55=double(app.LF(41).Img);
            [h,w,ch]=size(I55);
            if app.DropDown.Value=="EPI"
                M=length(app.LF);
                viewNum = sqrt(M);
                LF5D=zeros(viewNum,viewNum,h,w,ch);
                for u = 1 : viewNum
                    for v = 1 : viewNum
                        k = (u-1)*viewNum+v;
                        LF5D(u,v,:,:,:) = app.LF(k).Img;
                    end
                end
                [view_u,view_v,h,w,~]=size(LF5D);
                Disp=zeros(h,w);
                u=ceil(view_u/2);
                DispUp_vy=zeros(h,w);
                DispDown_vy=zeros(h,w);
                DistUp_vy=zeros(h,w);
                DistDown_vy=zeros(h,w);
                Disp_vy=zeros(h,w);
                app.TextArea.Value = "计算EPI--ux !";
                for x=1:h
                   if app.flag>0
                       return;
                   end
                    EPI_vy=squeeze(LF5D(u,:,x,:,:));
                    [DispUp_vy(x,:),DispDown_vy(x,:),DistUp_vy(x,:),DistDown_vy(x,:)]=EPIMeasure(EPI_vy);
                    app.Disparity=DispUp_vy;
                    DisparyRGB=(app.Disparity-min(app.Disparity(:)))./(max(app.Disparity(:))-min(app.Disparity(:)));
                    imshow(DisparyRGB,'Parent',app.UIAxes)
                    pause(1);
                end                       
                xy=DistUp_vy<=DistDown_vy;
                Disp_vy(xy)=DispUp_vy(xy);
                xy=DistUp_vy>DistDown_vy;
                Disp_vy(xy)=DispDown_vy(xy);
                temp_vy=min(DistUp_vy,DistDown_vy);
                v=ceil(view_v/2);
                DispUp_ux=zeros(h,w);
                DispDown_ux=zeros(h,w);
                DistUp_ux=zeros(h,w);
                DistDown_ux=zeros(h,w);
                Disp_ux=zeros(h,w);
                app.TextArea.Value = "计算EPI--vy !";
                for y=1:w
                    if app.flag>0
                       return;
                    end
                    EPI_ux=squeeze(LF5D(:,v,:,y,:));
                    [DispUp_ux(:,y),DispDown_ux(:,y),DistUp_ux(:,y),DistDown_ux(:,y)]=EPIMeasure(EPI_ux);
                    app.Disparity=DispUp_ux;
                    DisparyRGB=(app.Disparity-min(app.Disparity(:)))./(max(app.Disparity(:))-min(app.Disparity(:)));
                    imshow(DisparyRGB,'Parent',app.UIAxes)
                    pause(1);
                end
                xy=DistUp_ux<=DistDown_ux;
                Disp_ux(xy)=DispUp_ux(xy);
                xy=DistUp_ux>DistDown_ux;
                Disp_ux(xy)=DispDown_ux(xy);
                temp_ux=min(DistUp_ux,DistDown_ux);
                r = 3;                                                                  
                eps = 0.001; 
                app.TextArea.Value = "EPI--vy与 EPI--ux 融合!";
                I55=squeeze(LF5D(5,5,:,:,:));   
                Disp_ux=repairDisparity(I55, Disp_ux, r, eps);   
                Disp_vy=repairDisparity(I55, Disp_vy, r, eps);  
                app.Disparity=Disp_ux;
                DisparyRGB=(app.Disparity-min(app.Disparity(:)))./(max(app.Disparity(:))-min(app.Disparity(:)));
                imshow(DisparyRGB,'Parent',app.UIAxes)
                pause(1);
       
                xy=temp_ux<=temp_vy;
                Disp(xy)=Disp_ux(xy);
                xy=temp_ux>temp_vy;
                Disp(xy)=Disp_vy(xy);  
                app.Disparity=Disp;  
            else
                
               % 深度估计
                app.Disparity=zeros(h,w);
                temp=zeros(h,w);
                itr=1;numd=ceil((app.s_max-app.s_min)/app.d+1);
                temp1=zeros(h,w,numd);
                for s=app.s_min:app.d:app.s_max
                    
                   if app.flag>0
                       return;
                   end
                   cellArrayText = sprintf('Processing: %d/%d done!\n',itr,numd)  ;
                   app.TextArea.Value = cellArrayText;
                   itr=itr+1;               
                   switch app.DropDown.Value
                        case "Var"
                            raymeasure=rayMeasure(app.LF,s,h,w);
                        case "FVar"
                            raymeasure=rayFsiftMeasure(app.LF,s,h,w);
                        case "Mean"
                            raymeasure=-rayMeanMeasure(app.LF,s,h,w);
                        case "CAE"
                            raymeasure=CAEMeasure(app.LF,s,h,w);
                        case "wM"
                            raymeasure=wMeasure(app.LF,s,5,0);

                        case "Nor"
                            raymeasure=-rayNormalMeasure(app.LF,I55,s,h,w);
                        case "Cor"
                            raymeasure=wCorMeasure(app.LF,s,5,0);
                        case "MWTA"
                            raymeasure=MultiWTAMeasure(app.LF,s,5,0);
                        case "MW"
                            raymeasure=MultiWMeasure(app.LF,s,5,0);
                        case "Fourier"
                            raymeasure=rayFDMeasure(app.LF,s,h,w);
                        case "Hist"
                            raymeasure=-rayHistMeasure(app.LF,s,h,w,100);                            
                            
                        otherwise
                           raymeasure=-1*rayNormalMeasure(app.LF,app.LF(41).Img,s,h,w);
                   end
                   temp1(:,:,itr)=raymeasure;
                   if(s==app.s_min)
                      temp=raymeasure;
                      app.Disparity(:,:)=app.s_min;
                   else 
                      xy=temp>raymeasure;
                      app.Disparity(xy)=s;
                      temp(xy)=raymeasure(xy);
                   end   
                   DisparyRGB=(app.Disparity-min(app.Disparity(:)))./(max(app.Disparity(:))-min(app.Disparity(:)));
                   imshow(DisparyRGB,'Parent',app.UIAxes)
                   pause(1);
                end
                app.TextArea.Value = "初始深度图已生成!";
                pause(1);
                 
            end
                app.TextArea.Value = "进行深度图优化!";
                switch app.DropDown_2.Value
                   case '优化算法1'
                      r = 5;                                                                  
                      eps = 0.00001; 
                      I55=app.LF(41).Img; 
                      num=50;
                      for i=1:num
                            app.Disparity=repairDisparity(I55, app.Disparity, r, eps);
                            if mod(i,5)==0
                              DisparyRGB=(app.Disparity-min(app.Disparity(:)))./(max(app.Disparity(:))-min(app.Disparity(:)));
                              imshow(DisparyRGB,'Parent',app.UIAxes)
                              pause(1);
                            end
                      end
                      DisparyRGB=(app.Disparity-min(app.Disparity(:)))./(max(app.Disparity(:))-min(app.Disparity(:)));
                      imshow(DisparyRGB,'Parent',app.UIAxes)
                      pause(1);
                   case '优化算法2'
                      r = 9;                                                                  
                      eps = 0.001; 
                      I55=app.LF(41).Img; 
                      num=100;
                      for i=1:num
                           if app.flag>0
                              return;
                           end
                            app.Disparity=repairDisparity(I55, app.Disparity, r, eps);
                            if mod(i,10)==0
                              DisparyRGB=(app.Disparity-min(app.Disparity(:)))./(max(app.Disparity(:))-min(app.Disparity(:)));
                              imshow(DisparyRGB,'Parent',app.UIAxes)
                              pause(1);
                            end
                      end
                      DisparyRGB=(app.Disparity-min(app.Disparity(:)))./(max(app.Disparity(:))-min(app.Disparity(:)));
                      imshow(DisparyRGB,'Parent',app.UIAxes)
                      pause(1);
                    otherwise
                      r = 3;                                                                  
                      eps = 0.0001; 
                      I55=app.LF(41).Img; 
                      num=5;
                      for i=1:num
                            app.Disparity=repairDisparity(I55, app.Disparity, r, eps);
                            DisparyRGB=(app.Disparity-min(app.Disparity(:)))./(max(app.Disparity(:))-min(app.Disparity(:)));
                            imshow(DisparyRGB,'Parent',app.UIAxes)
                            pause(1); 
                      end
                end                                                          
            app.TextArea.Value = "深度图重建结束!";                        
        end

        % Button pushed function: initialization
        function initializationPushed(app, event)
            app.TextArea.Value = "初始化---";
            app.flag=1;
            addpath(genpath('.\LFMeasure'));
            addpath(genpath('.\LFDataLoad'));   
            app.file_path='.\lightfield\';   
            app.LFformat='png';
            app.s_max=2.0;
            app.s_min=-1.7;
            app.d=0.1;    
            imshow(ones(512,512),'Parent',app.UIAxes)
            app.TextArea.Value = "初始化---已完成!";
            drawnow();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {472, 472};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {199, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: LoadLF
        function LoadLFButtonPushed(app, event)
            app.TextArea.Value = "加载数据---";
            app.LF=loadLFData(app.file_path,app.LFformat);
            app.TextArea.Value = "加载数据---已完成!";
        end

        % Value changed function: DminEditField
        function DminEditFieldValueChanged(app, event)
            app.s_min = app.DminEditField.Value;   
            app.TextArea.Value = "s_min---已完成";
        end

        % Value changed function: DmaxEditField
        function DmaxEditFieldValueChanged(app, event)
            app.s_max = app.DmaxEditField.Value; 
            app.TextArea.Value = "s_max---已完成";
        end

        % Value changed function: dEditField
        function dEditFieldValueChanged(app, event)
             app.d = app.dEditField.Value;
             app.TextArea.Value = "d---已完成";
        end

        % Value changed function: LFformatEditField
        function LFformatEditFieldValueChanged2(app, event)
            app.LFformat = app.LFformatEditField.Value;    
            app.TextArea.Value = "LFformat----已完成!";
        end

        % Value changed function: LFpathEditField
        function LFpathEditFieldValueChanged2(app, event)
            app.file_path = app.LFpathEditField.Value;
            app.TextArea.Value = "LFpath----已完成!";
        end

        % Button pushed function: save
        function saveButtonPushed(app, event)
            save result.mat;
            app.TextArea.Value = "相关参数已保存到当前文件夹下";
        end

        % Button pushed function: initialization_2
        function initialization_2ButtonPushed(app, event)
            url = 'https://github.com/tzslg/tzslg--Application-instruction-of-depth-reconstruction-algorithm';
            web(url,'-browser');
            app.TextArea.Value = "正在访问--help";        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '深度重建';
            app.Ligenshi88comLabel = uilabel(app.LeftPanel);
            app.Ligenshi88comLabel.Text = {'Ligenshi@88.com'; ''; ''};
            app.CONTACTLabel = uilabel(app.LeftPanel);
            app.CONTACTLabel.Text = 'CONTACT';
            app.initialization = uibutton(app.LeftPanel, 'push');
            app.initialization.ButtonPushedFcn = createCallbackFcn(app, @initializationPushed, true);
            app.initialization.Text = '初始化';
            app.LoadLF = uibutton(app.LeftPanel, 'push');
            app.LoadLF.ButtonPushedFcn = createCallbackFcn(app, @LoadLFButtonPushed, true);
            app.LoadLF.Text = '加载数据';
            app.run = uibutton(app.LeftPanel, 'push');
            app.run.ButtonPushedFcn = createCallbackFcn(app, @runPushed, true);
            app.run.Text = 'RUN';
            app.save = uibutton(app.LeftPanel, 'push');
            app.save.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.save.Text = '保存结果';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.DminEditFieldLabel = uilabel(app.RightPanel);
            app.DminEditFieldLabel.Text = 'Dmin';
            app.DminEditField = uieditfield(app.RightPanel, 'numeric');
            app.DminEditField.ValueChangedFcn = createCallbackFcn(app, @DminEditFieldValueChanged, true);
            app.DmaxEditFieldLabel = uilabel(app.RightPanel);
            app.DmaxEditFieldLabel.Text = 'Dmax';
            app.DmaxEditField = uieditfield(app.RightPanel, 'numeric');
            app.DmaxEditField.ValueChangedFcn = createCallbackFcn(app, @DmaxEditFieldValueChanged, true);
            app.dEditFieldLabel = uilabel(app.RightPanel);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.RightPanel, 'numeric');
            app.dEditField.ValueChangedFcn = createCallbackFcn(app, @dEditFieldValueChanged, true);
            app.LFformatEditField_2Label = uilabel(app.RightPanel);
            app.LFformatEditField_2Label.Text = 'LFformat';
            app.LFformatEditField = uieditfield(app.RightPanel, 'text');
            app.LFformatEditField.ValueChangedFcn = createCallbackFcn(app, @LFformatEditFieldValueChanged2, true);
            app.LFpathEditField_2Label = uilabel(app.RightPanel);
            app.LFpathEditField_2Label.Text = 'LFpath';
            app.LFpathEditField = uieditfield(app.RightPanel, 'text');
            app.LFpathEditField.ValueChangedFcn = createCallbackFcn(app, @LFpathEditFieldValueChanged2, true);
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '重建算法';
            app.DropDown = uidropdown(app.RightPanel);
            app.DropDown.Items = {'默认', 'Nor', 'Var', 'FVar', 'Mean', 'CAE', 'Cor', 'MWTA', 'wM', 'MW', 'Fourier', 'Hist', 'EPI', '自适应算法', '颜色空间算法', 'FDL'};
            app.DropDown.Value = '默认';
            app.Label_3 = uilabel(app.RightPanel);
            app.Label_3.Text = '优化算法';
            app.DropDown_2 = uidropdown(app.RightPanel);
            app.DropDown_2.Items = {'默认', '优化算法1', '优化算法2', '优化算法3', '优化算法4', '优化算法5', '优化算法6', '优化算法7', ''};
            app.DropDown_2.Value = '默认';
            app.initialization_2 = uibutton(app.RightPanel, 'push');
            app.initialization_2.ButtonPushedFcn = createCallbackFcn(app, @initialization_2ButtonPushed, true);
            app.initialization_2.Text = 'Help';
            app.Label_4 = uilabel(app.RightPanel);
            app.Label_4.Text = '运行状态';
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea.Value = {'欢迎使用'};
            app.Label_5 = uilabel(app.RightPanel);
            app.Label_5.Text = '相关参数设置';
            app.DISPALYLabel = uilabel(app.RightPanel);
            app.DISPALYLabel.Text = 'DISPALY';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DepthReconstruction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end